classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        CommunicateButton  matlab.ui.control.Button
        LedONButton        matlab.ui.control.Button
        LedOFFButton       matlab.ui.control.Button
        CheckBox_1         matlab.ui.control.CheckBox
        CheckBox_2         matlab.ui.control.CheckBox
        CheckBox_3         matlab.ui.control.CheckBox
        CheckBox_4         matlab.ui.control.CheckBox
        CheckBox_5         matlab.ui.control.CheckBox
        CheckBox_6         matlab.ui.control.CheckBox
        CheckBox_7         matlab.ui.control.CheckBox
        CheckBox_8         matlab.ui.control.CheckBox
        CheckBox_9         matlab.ui.control.CheckBox
        CheckBox_10        matlab.ui.control.CheckBox
        CheckBox_11        matlab.ui.control.CheckBox
        CheckBox_12        matlab.ui.control.CheckBox
        CheckBox_13        matlab.ui.control.CheckBox
        CheckBox_14        matlab.ui.control.CheckBox
        CheckBox_15        matlab.ui.control.CheckBox
        CheckBox_16        matlab.ui.control.CheckBox
        CheckBox_17        matlab.ui.control.CheckBox
        CheckBox_18        matlab.ui.control.CheckBox
        CheckBox_19        matlab.ui.control.CheckBox
        CheckBox_20        matlab.ui.control.CheckBox
        CheckBox_21        matlab.ui.control.CheckBox
        CheckBox_22        matlab.ui.control.CheckBox
        CheckBox_23        matlab.ui.control.CheckBox
        CheckBox_24        matlab.ui.control.CheckBox
        CheckBox_25        matlab.ui.control.CheckBox
        CheckBox_26        matlab.ui.control.CheckBox
        CheckBox_27        matlab.ui.control.CheckBox
        CheckBox_28        matlab.ui.control.CheckBox
        CheckBox_29        matlab.ui.control.CheckBox
        CheckBox_30        matlab.ui.control.CheckBox
        CheckBox_31        matlab.ui.control.CheckBox
        CheckBox_32        matlab.ui.control.CheckBox
        CheckBox_33        matlab.ui.control.CheckBox
        CheckBox_34        matlab.ui.control.CheckBox
        CheckBox_35        matlab.ui.control.CheckBox
        CheckBox_36        matlab.ui.control.CheckBox
        Slider             matlab.ui.control.Slider
        PLOTButton         matlab.ui.control.Button
        PRINTButton        matlab.ui.control.Button
        SAVEButton         matlab.ui.control.Button
        EXITButton         matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        a,fig
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CommunicateButton
        function CommunicateButtonPushed(app, event)
            app.a=arduino();
            configurePin(app.a,'A0',"AnalogInput");
            configurePin(app.a,'D3','PWM');
            configurePin(app.a,'D5','PWM');
            configurePin(app.a,'D6','PWM');
            configurePin(app.a,'D9','PWM');
            configurePin(app.a,'D10','PWM');
            configurePin(app.a,'D11','PWM');
        end

        % Button pushed function: LedONButton
        function LedONButtonPushed(app, event)
            sliderval = app.Slider.Value;
            if  app.CheckBox_1.Value || app.CheckBox_2.Value || app.CheckBox_3.Value || app.CheckBox_4.Value  || app.CheckBox_5.Value  || app.CheckBox_6.Value
                writePWMDutyCycle(app.a, 'D3', sliderval/100.0);
            end
            if app.CheckBox_7.Value || app.CheckBox_8.Value || app.CheckBox_9.Value || app.CheckBox_10.Value  || app.CheckBox_11.Value  || app.CheckBox_12.Value
                writePWMDutyCycle(app.a, 'D5', sliderval/100.0);
            end
            if app.CheckBox_13.Value || app.CheckBox_14.Value || app.CheckBox_15.Value || app.CheckBox_16.Value  || app.CheckBox_17.Value  || app.CheckBox_18.Value
                writePWMDutyCycle(app.a, 'D6', sliderval/100.0);
            end
            if app.CheckBox_19.Value || app.CheckBox_20.Value || app.CheckBox_21.Value || app.CheckBox_22.Value  || app.CheckBox_23.Value  || app.CheckBox_24.Value
                writePWMDutyCycle(app.a, 'D9', sliderval/100.0);
            end
            if app.CheckBox_25.Value || app.CheckBox_26.Value || app.CheckBox_27.Value || app.CheckBox_28.Value  || app.CheckBox_29.Value  || app.CheckBox_30.Value
                writePWMDutyCycle(app.a, 'D10', sliderval/100.0);
            end
            if app.CheckBox_31.Value || app.CheckBox_32.Value || app.CheckBox_33.Value || app.CheckBox_34.Value  || app.CheckBox_35.Value  || app.CheckBox_36.Value
                writePWMDutyCycle(app.a, 'D11', sliderval/100.0);
            end
            
            if  app.CheckBox_1.Value || app.CheckBox_7.Value || app.CheckBox_13.Value || app.CheckBox_19.Value  || app.CheckBox_25.Value  || app.CheckBox_31.Value
                writeDigitalPin(app.a,'D2',1)
            end
            if app.CheckBox_2.Value || app.CheckBox_8.Value || app.CheckBox_14.Value || app.CheckBox_20.Value  || app.CheckBox_26.Value  || app.CheckBox_32.Value
                writeDigitalPin(app.a,'D4',1)
            end
            if app.CheckBox_3.Value || app.CheckBox_9.Value || app.CheckBox_15.Value || app.CheckBox_21.Value  || app.CheckBox_27.Value  || app.CheckBox_33.Value
                writeDigitalPin(app.a,'D7',1)
            end
            if app.CheckBox_4.Value || app.CheckBox_10.Value || app.CheckBox_16.Value || app.CheckBox_22.Value  || app.CheckBox_28.Value  || app.CheckBox_34.Value
                writeDigitalPin(app.a,'D8',1)
            end
            if app.CheckBox_5.Value || app.CheckBox_11.Value || app.CheckBox_17.Value || app.CheckBox_23.Value  || app.CheckBox_29.Value  || app.CheckBox_35.Value
                writeDigitalPin(app.a,'D12',1)
            end
            if app.CheckBox_6.Value || app.CheckBox_12.Value || app.CheckBox_18.Value || app.CheckBox_24.Value  || app.CheckBox_30.Value  || app.CheckBox_36.Value
                writeDigitalPin(app.a,'D13',1)
            end
        end

        % Button pushed function: LedOFFButton
        function LedOFFButtonPushed(app, event)
            writeDigitalPin(app.a,'D2',0)
            writeDigitalPin(app.a,'D3',0)
            writeDigitalPin(app.a,'D4',0)
            writeDigitalPin(app.a,'D5',0)
            writeDigitalPin(app.a,'D6',0)
            writeDigitalPin(app.a,'D7',0)
            writeDigitalPin(app.a,'D8',0)
            writeDigitalPin(app.a,'D9',0)
            writeDigitalPin(app.a,'D10',0)
            writeDigitalPin(app.a,'D11',0)
            writeDigitalPin(app.a,'D12',0)
            writeDigitalPin(app.a,'D13',0)
            app.CheckBox_1.Value = false ;
            app.CheckBox_2.Value = false ;
            app.CheckBox_3.Value = false ;
            app.CheckBox_4.Value = false ;
            app.CheckBox_5.Value = false ;
            app.CheckBox_6.Value = false ;
            app.CheckBox_7.Value = false ;
            app.CheckBox_8.Value = false ;
            app.CheckBox_9.Value = false ;
            app.CheckBox_10.Value = false ;
            app.CheckBox_11.Value = false ;
            app.CheckBox_12.Value = false ;
            app.CheckBox_13.Value = false ;
            app.CheckBox_14.Value = false ;
            app.CheckBox_15.Value = false ;
            app.CheckBox_16.Value = false ;
            app.CheckBox_17.Value = false ;
            app.CheckBox_18.Value = false ;
            app.CheckBox_19.Value = false ;
            app.CheckBox_20.Value = false ;
            app.CheckBox_21.Value = false ;
            app.CheckBox_22.Value = false ;
            app.CheckBox_23.Value = false ;
            app.CheckBox_24.Value = false ;
            app.CheckBox_25.Value = false ;
            app.CheckBox_26.Value = false ;
            app.CheckBox_27.Value = false ;
            app.CheckBox_28.Value = false ;
            app.CheckBox_29.Value = false ;
            app.CheckBox_30.Value = false ;
            app.CheckBox_31.Value = false ;
            app.CheckBox_32.Value = false ;
            app.CheckBox_33.Value = false ;
            app.CheckBox_34.Value = false ;
            app.CheckBox_35.Value = false ;
            app.CheckBox_36.Value = false ;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            %changingValue = event.Value;
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            format bank
            app.fig = figure("Visible","off");
            t = linspace(0,5,50);
            y = zeros(1,50);
            for c = 1:50
                y(c) = readVoltage(app.a,'A0');
                plot(t,y);
                plot(app.UIAxes,t,y)
                pause(0.1)
            end
            
        end

        % Button pushed function: PRINTButton
        function PRINTButtonPushed(app, event)
            print(app.fig);
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            saveas(app.fig,"figure.png");
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CommunicateButton = uibutton(app.UIFigure, 'push');
            app.CommunicateButton.ButtonPushedFcn = createCallbackFcn(app, @CommunicateButtonPushed, true);
            app.CommunicateButton.Text = 'Communicate';
            app.LedONButton = uibutton(app.UIFigure, 'push');
            app.LedONButton.ButtonPushedFcn = createCallbackFcn(app, @LedONButtonPushed, true);
            app.LedONButton.Text = 'Led ON';
            app.LedOFFButton = uibutton(app.UIFigure, 'push');
            app.LedOFFButton.ButtonPushedFcn = createCallbackFcn(app, @LedOFFButtonPushed, true);
            app.LedOFFButton.Text = 'Led OFF';
            app.CheckBox_1 = uicheckbox(app.UIFigure);
            app.CheckBox_1.Text = '';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.UIFigure);
            app.CheckBox_6.Text = '';
            app.CheckBox_7 = uicheckbox(app.UIFigure);
            app.CheckBox_7.Text = '';
            app.CheckBox_8 = uicheckbox(app.UIFigure);
            app.CheckBox_8.Text = '';
            app.CheckBox_9 = uicheckbox(app.UIFigure);
            app.CheckBox_9.Text = '';
            app.CheckBox_10 = uicheckbox(app.UIFigure);
            app.CheckBox_10.Text = '';
            app.CheckBox_11 = uicheckbox(app.UIFigure);
            app.CheckBox_11.Text = '';
            app.CheckBox_12 = uicheckbox(app.UIFigure);
            app.CheckBox_12.Text = '';
            app.CheckBox_13 = uicheckbox(app.UIFigure);
            app.CheckBox_13.Text = '';
            app.CheckBox_14 = uicheckbox(app.UIFigure);
            app.CheckBox_14.Text = '';
            app.CheckBox_15 = uicheckbox(app.UIFigure);
            app.CheckBox_15.Text = '';
            app.CheckBox_16 = uicheckbox(app.UIFigure);
            app.CheckBox_16.Text = '';
            app.CheckBox_17 = uicheckbox(app.UIFigure);
            app.CheckBox_17.Text = '';
            app.CheckBox_18 = uicheckbox(app.UIFigure);
            app.CheckBox_18.Text = '';
            app.CheckBox_19 = uicheckbox(app.UIFigure);
            app.CheckBox_19.Text = '';
            app.CheckBox_20 = uicheckbox(app.UIFigure);
            app.CheckBox_20.Text = '';
            app.CheckBox_21 = uicheckbox(app.UIFigure);
            app.CheckBox_21.Text = '';
            app.CheckBox_22 = uicheckbox(app.UIFigure);
            app.CheckBox_22.Text = '';
            app.CheckBox_23 = uicheckbox(app.UIFigure);
            app.CheckBox_23.Text = '';
            app.CheckBox_24 = uicheckbox(app.UIFigure);
            app.CheckBox_24.Text = '';
            app.CheckBox_25 = uicheckbox(app.UIFigure);
            app.CheckBox_25.Text = '';
            app.CheckBox_26 = uicheckbox(app.UIFigure);
            app.CheckBox_26.Text = '';
            app.CheckBox_27 = uicheckbox(app.UIFigure);
            app.CheckBox_27.Text = '';
            app.CheckBox_28 = uicheckbox(app.UIFigure);
            app.CheckBox_28.Text = '';
            app.CheckBox_29 = uicheckbox(app.UIFigure);
            app.CheckBox_29.Text = '';
            app.CheckBox_30 = uicheckbox(app.UIFigure);
            app.CheckBox_30.Text = '';
            app.CheckBox_31 = uicheckbox(app.UIFigure);
            app.CheckBox_31.Text = '';
            app.CheckBox_32 = uicheckbox(app.UIFigure);
            app.CheckBox_32.Text = '';
            app.CheckBox_33 = uicheckbox(app.UIFigure);
            app.CheckBox_33.Text = '';
            app.CheckBox_34 = uicheckbox(app.UIFigure);
            app.CheckBox_34.Text = '';
            app.CheckBox_35 = uicheckbox(app.UIFigure);
            app.CheckBox_35.Text = '';
            app.CheckBox_36 = uicheckbox(app.UIFigure);
            app.CheckBox_36.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.PRINTButton = uibutton(app.UIFigure, 'push');
            app.PRINTButton.ButtonPushedFcn = createCallbackFcn(app, @PRINTButtonPushed, true);
            app.PRINTButton.Text = 'PRINT';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Light')
            xlabel(app.UIAxes, 'Time(Seconds)')
            ylabel(app.UIAxes, 'Lumen')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end